classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        StartButton                  matlab.ui.control.Button
        ExtremelyHighEndSliderLabel  matlab.ui.control.Label
        ExtremelyHighEndSlider       matlab.ui.control.Slider
        RestartButton                matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        HighEndSliderLabel           matlab.ui.control.Label
        HighEndSlider                matlab.ui.control.Slider
        PresenceRangeSliderLabel     matlab.ui.control.Label
        PresenceRangeSlider          matlab.ui.control.Slider
        UpperMidrangeSliderLabel     matlab.ui.control.Label
        UpperMidrangeSlider          matlab.ui.control.Slider
        MiddleMidrangeSliderLabel    matlab.ui.control.Label
        MiddleMidrangeSlider         matlab.ui.control.Slider
        LowerMidrangeSliderLabel     matlab.ui.control.Label
        LowerMidrangeSlider          matlab.ui.control.Slider
        UpperBassSliderLabel         matlab.ui.control.Label
        UpperBassSlider              matlab.ui.control.Slider
        MidBassSliderLabel           matlab.ui.control.Label
        MidBassSlider                matlab.ui.control.Slider
        LowBassSliderLabel           matlab.ui.control.Label
        LowBassSlider                matlab.ui.control.Slider
        DeepBassSlider               matlab.ui.control.Slider
        DeepBassSliderLabel          matlab.ui.control.Label
        Loadamp3fileButton           matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UIAxes2_2                    matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        file
        path
        w
        t
        f
        x
        y
        fs
        n
        Fxs
        fourier
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Loadamp3fileButton
        function Loadamp3fileButtonPushed(app, event)
            
            [app.file, app.path] = uigetfile('*.mp3');  

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            sound(app.y,app.fs); % escuchamos la reconstruccion

        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            
%           Hacemos que los factores de amplificacion sean 1
            app.DeepBassSlider.Value = 1;
            app.ExtremelyHighEndSlider.Value = 1;
            app.HighEndSlider.Value = 1;
            app.PresenceRangeSlider.Value = 1;
            app.UpperMidrangeSlider.Value = 1;
            app.MiddleMidrangeSlider.Value = 1;
            app.LowerMidrangeSlider.Value = 1;
            app.UpperBassSlider.Value = 1;
            app.MidBassSlider.Value = 1;
            app.LowBassSlider.Value = 1;
            
            % Creación ventanas
            app.w = windows(app.n,app.fs);

            % Deep Bass
            k = 1;
            temp = app.w(2,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.DeepBassSlider.Value;
                end
                k = k + 1;
            end
            app.w(2,:) = temp;

            % Low Bass
            k = 1;
            temp = app.w(3,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.LowBassSlider.Value;
                end
                k = k + 1;
            end
            app.w(3,:) = temp;            
            
            % Mid Bass
            k = 1;
            temp = app.w(4,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.MidBassSlider.Value;
                end
                k = k + 1;
            end
            app.w(4,:) = temp;

            % Upper Bass
            k = 1;
            temp = app.w(5,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.UpperBassSlider.Value;
                end
                k = k + 1;
            end
            app.w(5,:) = temp;

            % Lower Midrange
            k = 1;
            temp = app.w(6,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.LowerMidrangeSlider.Value;
                end
                k = k + 1;
            end
            app.w(6,:) = temp;

            % Middle Midrange
            k = 1;
            temp = app.w(7,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.MiddleMidrangeSlider.Value;
                end
                k = k + 1;
            end
            app.w(7,:) = temp;

            % Upper Midrange
            k = 1;
            temp = app.w(8,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.UpperMidrangeSlider.Value;
                end
                k = k + 1;
            end
            app.w(8,:) = temp;

            % Presence Range
            k = 1;
            temp = app.w(9,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.PresenceRangeSlider.Value;
                end
                k = k + 1;
            end
            app.w(9,:) = temp;

            % High End
            k = 1;
            temp = app.w(10,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.HighEndSlider.Value;
                end
                k = k + 1;
            end
            app.w(10,:) = temp;

            % Extremely High End
            k = 1;
            temp = app.w(11,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.ExtremelyHighEndSlider.Value;
                end
                k = k + 1;
            end
            app.w(11,:) = temp;


            % Calculando inversa
            app.fourier = zeros(app.n,1);
            for j=1:12
                temp = app.Fxs.*app.w(j,:)';
                app.fourier = app.fourier + temp;
            end

            Fx2 = ifftshift(app.fourier);
            app.y = real(ifft(Fx2));
            app.y = app.y./max(app.y);            
            plot(app.UIAxes,app.t,app.x);
            grid(app.UIAxes,"on");           
            plot(app.UIAxes_2,app.t,app.y);
            grid(app.UIAxes_2,"on");

            plot(app.UIAxes2,app.f,abs(app.Fxs));
            grid(app.UIAxes2,"on");
            plot(app.UIAxes2_2,app.f,abs(app.fourier));
            grid(app.UIAxes2_2,"on");

        end

        % Callback function: DeepBassSlider, ExtremelyHighEndSlider, 
        % HighEndSlider, LowBassSlider, LowerMidrangeSlider, 
        % MidBassSlider, MiddleMidrangeSlider, PresenceRangeSlider, 
        % StartButton, UpperBassSlider, UpperMidrangeSlider
        function StartButtonPushed(app, event)
            
            [~, app.fs] = audioread(fullfile(app.path,app.file));
            sample = [1, 2*app.fs];
            [xn, app.fs] = audioread(fullfile(app.path,app.file), sample); 
            app.x = xn(:,1);
            app.x = app.x./max(app.x);
            app.n = length(app.x);
            app.t = (0:app.n-1)/app.fs; % Creando un vector de tiempo
            
            Fx = fft(app.x); % Algoritmo para calcular los valores de la Transformada de Fourier
            app.Fxs = fftshift(Fx); % Corrimiento de la transformada para obtener valores negativos y positivos. 
            app.f = (-app.n/2:app.n/2-1)*app.fs/app.n;

            % Creación ventanas
            app.w = windows(app.n,app.fs);
            
            % Deep Bass
            k = 1;
            temp = app.w(2,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.DeepBassSlider.Value;
                end
                k = k + 1;
            end
            app.w(2,:) = temp;

            % Low Bass
            k = 1;
            temp = app.w(3,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.LowBassSlider.Value;
                end
                k = k + 1;
            end
            app.w(3,:) = temp;            
            
            % Mid Bass
            k = 1;
            temp = app.w(4,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.MidBassSlider.Value;
                end
                k = k + 1;
            end
            app.w(4,:) = temp;

            % Upper Bass
            k = 1;
            temp = app.w(5,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.UpperBassSlider.Value;
                end
                k = k + 1;
            end
            app.w(5,:) = temp;

            % Lower Midrange
            k = 1;
            temp = app.w(6,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.LowerMidrangeSlider.Value;
                end
                k = k + 1;
            end
            app.w(6,:) = temp;

            % Middle Midrange
            k = 1;
            temp = app.w(7,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.MiddleMidrangeSlider.Value;
                end
                k = k + 1;
            end
            app.w(7,:) = temp;

            % Upper Midrange
            k = 1;
            temp = app.w(8,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.UpperMidrangeSlider.Value;
                end
                k = k + 1;
            end
            app.w(8,:) = temp;

            % Presence Range
            k = 1;
            temp = app.w(9,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.PresenceRangeSlider.Value;
                end
                k = k + 1;
            end
            app.w(9,:) = temp;

            % High End
            k = 1;
            temp = app.w(10,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.HighEndSlider.Value;
                end
                k = k + 1;
            end
            app.w(10,:) = temp;

            % Extremely High End
            k = 1;
            temp = app.w(11,:);
            while k <= length(temp)
                if temp(k) == 1
                    temp(k) = app.ExtremelyHighEndSlider.Value;
                end
                k = k + 1;
            end
            app.w(11,:) = temp;

            % Calculando inversa
            app.fourier = zeros(app.n,1);
            for j=1:12
                temp = app.Fxs.*app.w(j,:)';
                app.fourier = app.fourier + temp;
            end

            Fx2 = ifftshift(app.fourier);
            app.y = real(ifft(Fx2));
            app.y = app.y./max(app.y);            
            plot(app.UIAxes,app.t,app.x);
            grid(app.UIAxes,"on");           
            plot(app.UIAxes_2,app.t,app.y);
            grid(app.UIAxes_2,"on");

            plot(app.UIAxes2,app.f,abs(app.Fxs));
            grid(app.UIAxes2,"on");
            plot(app.UIAxes2_2,app.f,abs(app.fourier));
            grid(app.UIAxes2_2,"on");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {707, 690};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {361, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Loadamp3fileButton = uibutton(app.LeftPanel, 'push');
            app.Loadamp3fileButton.ButtonPushedFcn = createCallbackFcn(app, @Loadamp3fileButtonPushed, true);
            app.Loadamp3fileButton.Text = 'Load a mp3 file';
            app.DeepBassSliderLabel = uilabel(app.LeftPanel);
            app.DeepBassSliderLabel.Text = 'Deep Bass';
            app.DeepBassSlider = uislider(app.LeftPanel);
            app.DeepBassSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.DeepBassSlider.Value = 1;
            app.LowBassSlider = uislider(app.LeftPanel);
            app.LowBassSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.LowBassSlider.Value = 1;
            app.LowBassSliderLabel = uilabel(app.LeftPanel);
            app.LowBassSliderLabel.Text = 'Low Bass';
            app.MidBassSlider = uislider(app.LeftPanel);
            app.MidBassSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.MidBassSlider.Value = 1;
            app.MidBassSliderLabel = uilabel(app.LeftPanel);
            app.MidBassSliderLabel.Text = 'Mid Bass';
            app.UpperBassSlider = uislider(app.LeftPanel);
            app.UpperBassSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.UpperBassSlider.Value = 1;
            app.UpperBassSliderLabel = uilabel(app.LeftPanel);
            app.UpperBassSliderLabel.Text = 'Upper Bass';
            app.LowerMidrangeSlider = uislider(app.LeftPanel);
            app.LowerMidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.LowerMidrangeSlider.Value = 1;
            app.LowerMidrangeSliderLabel = uilabel(app.LeftPanel);
            app.LowerMidrangeSliderLabel.Text = 'Lower Midrange';
            app.MiddleMidrangeSlider = uislider(app.LeftPanel);
            app.MiddleMidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.MiddleMidrangeSlider.Value = 1;
            app.MiddleMidrangeSliderLabel = uilabel(app.LeftPanel);
            app.MiddleMidrangeSliderLabel.Text = 'Middle Midrange';
            app.UpperMidrangeSlider = uislider(app.LeftPanel);
            app.UpperMidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.UpperMidrangeSlider.Value = 1;
            app.UpperMidrangeSliderLabel = uilabel(app.LeftPanel);
            app.UpperMidrangeSliderLabel.Text = 'Upper Midrange';
            app.PresenceRangeSlider = uislider(app.LeftPanel);
            app.PresenceRangeSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PresenceRangeSlider.Value = 1;
            app.PresenceRangeSliderLabel = uilabel(app.LeftPanel);
            app.PresenceRangeSliderLabel.Text = 'Presence Range';
            app.HighEndSlider = uislider(app.LeftPanel);
            app.HighEndSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.HighEndSlider.Value = 1;
            app.HighEndSliderLabel = uilabel(app.LeftPanel);
            app.HighEndSliderLabel.Text = 'High End';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RestartButton = uibutton(app.LeftPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.ExtremelyHighEndSlider = uislider(app.LeftPanel);
            app.ExtremelyHighEndSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.ExtremelyHighEndSlider.Value = 1;
            app.ExtremelyHighEndSliderLabel = uilabel(app.LeftPanel);
            app.ExtremelyHighEndSliderLabel.Text = 'Extremely High End';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Señal Grabada')
            xlabel(app.UIAxes, 'tiempo [s]')
            ylabel(app.UIAxes, 'Amplitud [u.a.]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Magnitud de la Transformada de Fourier de la Señal')
            xlabel(app.UIAxes2, 'Frecuencia [Hz]')
            ylabel(app.UIAxes2, 'Amplitud [u.a.]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Señal Modificada')
            xlabel(app.UIAxes_2, 'tiempo [s]')
            ylabel(app.UIAxes_2, 'Amplitud [u.a.]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'Magnitud de la Transformada de Fourier de la Señal modificada')
            xlabel(app.UIAxes2_2, 'Frecuencia [Hz]')
            ylabel(app.UIAxes2_2, 'Amplitud [u.a.]')
            zlabel(app.UIAxes2_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end